classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        con1Label                matlab.ui.control.Label
        SchuhA                   matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        con2Label                matlab.ui.control.Label
        SchuhA_2                 matlab.ui.control.Slider
        Label_3                  matlab.ui.control.Label
        con3Label                matlab.ui.control.Label
        SchuhA_3                 matlab.ui.control.Slider
        Label_4                  matlab.ui.control.Label
        con4Label                matlab.ui.control.Label
        SchuhA_4                 matlab.ui.control.Slider
        saveButton               matlab.ui.control.StateButton
        OrderShoeEditFieldLabel  matlab.ui.control.Label
        OrderShoeEditField       matlab.ui.control.EditField
        OkButton                 matlab.ui.control.StateButton
        sepreateconditionswithcommaLabel  matlab.ui.control.Label
        SubjectIDEditFieldLabel  matlab.ui.control.Label
        SubjectIDEditField       matlab.ui.control.EditField
        MassEditFieldLabel       matlab.ui.control.Label
        MassEditField            matlab.ui.control.NumericEditField
        kgLabel                  matlab.ui.control.Label
        HeightEditFieldLabel     matlab.ui.control.Label
        HeightEditField          matlab.ui.control.NumericEditField
        mLabel                   matlab.ui.control.Label
        GenderButtonGroup        matlab.ui.container.ButtonGroup
        femaleButton             matlab.ui.control.RadioButton
        maleButton               matlab.ui.control.RadioButton
        MilageEditFieldLabel     matlab.ui.control.Label
        MilageEditField          matlab.ui.control.NumericEditField
        QuestionsButtonGroup     matlab.ui.container.ButtonGroup
        ComiButton               matlab.ui.control.RadioButton
        ForFootStabButton        matlab.ui.control.RadioButton
        BackFootStabButton       matlab.ui.control.RadioButton
        AllStabButton            matlab.ui.control.RadioButton
        SizeButton               matlab.ui.control.RadioButton
        kmweekLabel              matlab.ui.control.Label
        badLabel                 matlab.ui.control.Label
        goodLabel                matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: SchuhA
        function SchuhAValueChanged(app, event)
            value = app.SchuhA.Value;
            testung = num2str(value)
            app.Label.Text = testung
            
        end

        % Value changed function: SchuhA_2
        function SchuhA_2ValueChanged(app, event)
            value2 = app.SchuhA_2.Value;
            testung2 = num2str(value2)
            app.Label_2.Text = testung2
            
        end

        % Value changed function: SchuhA_3
        function SchuhA_3ValueChanged(app, event)
            value3 = app.SchuhA_3.Value;
            testung3 = num2str(value3)
            app.Label_3.Text = testung3
        end

        % Value changed function: SchuhA_4
        function SchuhA_4ValueChanged(app, event)
            value4 = app.SchuhA_4.Value;
            testung4 = num2str(value4)
            app.Label_4.Text = testung4
        end

        % Callback function
        function NextButtonPushed(app, event)
            
        end

        % Callback function
        function uebernehmenButtonValueChanged(app, event)
            
        end

        % Value changed function: OkButton
        function OkButtonValueChanged(app, event)
            valueok = app.OkButton.Value;
            if valueok ==1
                app.OkButton.BackgroundColor = [0 1 0];
                app.OrderShoeEditField.Value = app.OrderShoeEditField.Value
                tempshoe = strsplit(app.OrderShoeEditField.Value, ',')
                app.con1Label.Text = tempshoe(1,1);
                app.con2Label.Text = tempshoe(1,2);
                app.con3Label.Text = tempshoe(1,3);
                app.con4Label.Text = tempshoe(1,4);
                probinfo.mass = app.MassEditField.Value
                probinfo.height = app.HeightEditField.Value
                probinfo.ID = (app.SubjectIDEditField.Value)
                probinfo.shoeorder = tempshoe
                if app.femaleButton.Value == true
                    probinfo.gender = 'Female'
                elseif app.maleButton.Value == true
                    probinfo.gender = 'Male'
                end
                %                 tempquestions = strsplit(app.QuestionEditField.Value, ',')
                
                
                probinfo.kmperweek = app.MilageEditField.Value;
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename, 'probinfo')
                app.MassEditField.Visible = 'off';
                app.HeightEditField.Visible = 'off';
                app.GenderButtonGroup.Visible = 'off';
                app.MilageEditField.Visible = 'off';
                app.kgLabel.Visible = 'off';
                app.mLabel.Visible = 'off';
                app.MassEditFieldLabel.Text = ''
                app.MilageEditFieldLabel.Text = ''
                app.HeightEditFieldLabel.Text = ''
                app.kmweekLabel.Text = '';
                
                app.SizeButton.Value = true;
            else
                app.OkButton.BackgroundColor = [1 0 0];
            end
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            value = app.SubjectIDEditField.Value;
            subjectID = value;
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            value = app.MassEditField.Value;
            
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            
        end

        % Selection changed function: GenderButtonGroup
        function GenderButtonGroupSelectionChanged(app, event)
            selectedButton = app.GenderButtonGroup.SelectedObject;
            
        end

        % Callback function
        function versuchButtonPushed(app, event)
            
            
        end

        % Callback function
        function uebernehmenButtonValueChanged2(app, event)
            value = app.saveButton.Value;
            
        end

        % Value changed function: saveButton
        function saveButtonValueChanged3(app, event)
            value = app.saveButton.Value;
            tempquestions=('Comfort,ForFootStab,BackFootStab,AllStab,Size')
            tempquestions = strsplit(tempquestions, ',')
            
            
            if value==1 && app.ComiButton.Value == true
                
                DATA.(tempquestions{1, 1}).(app.SubjectIDEditField.Value)(1,1)=app.SchuhA.Value
                DATA.(tempquestions{1, 1}).(app.SubjectIDEditField.Value)(1,2)=app.SchuhA_2.Value
                DATA.(tempquestions{1, 1}).(app.SubjectIDEditField.Value)(1,3)=app.SchuhA_3.Value
                DATA.(tempquestions{1, 1}).(app.SubjectIDEditField.Value)(1,4)=app.SchuhA_4.Value
                app.ComiButton.FontColor = [0 1 0];
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename, 'DATA', '-append')
                app.SchuhA.Value = 50;
                app.SchuhA_2.Value = 50;
                app.SchuhA_3.Value = 50;
                app.SchuhA_4.Value = 50;
             
            elseif  value==1 && app.ForFootStabButton.Value == true
                
                DATA2.(tempquestions{1, 2}).(app.SubjectIDEditField.Value)(1,1)=app.SchuhA.Value
                DATA2.(tempquestions{1, 2}).(app.SubjectIDEditField.Value)(1,2)=app.SchuhA_2.Value
                DATA2.(tempquestions{1, 2}).(app.SubjectIDEditField.Value)(1,3)=app.SchuhA_3.Value
                DATA2.(tempquestions{1, 2}).(app.SubjectIDEditField.Value)(1,4)=app.SchuhA_4.Value
                app.ForFootStabButton.FontColor = [0 1 0];
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename,'DATA2', '-append')
                app.SchuhA.Value = 50;
                app.SchuhA_2.Value = 50;
                app.SchuhA_3.Value = 50;
                app.SchuhA_4.Value = 50;
            elseif  value==1 && app.BackFootStabButton.Value == true
                DATA5.(tempquestions{1, 3}).(app.SubjectIDEditField.Value)(1,1)=app.SchuhA.Value
                DATA5.(tempquestions{1, 3}).(app.SubjectIDEditField.Value)(1,2)=app.SchuhA_2.Value
                DATA5.(tempquestions{1, 3}).(app.SubjectIDEditField.Value)(1,3)=app.SchuhA_3.Value
                DATA5.(tempquestions{1, 3}).(app.SubjectIDEditField.Value)(1,4)=app.SchuhA_4.Value
                app.BackFootStabButton.FontColor = [0 1 0];
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename,'DATA5', '-append')
                app.SchuhA.Value = 50;
                app.SchuhA_2.Value = 50;
                app.SchuhA_3.Value = 50;
                app.SchuhA_4.Value = 50;
                
            elseif  value==1 && app.AllStabButton.Value == true
                
                DATA3.(tempquestions{1, 4}).(app.SubjectIDEditField.Value)(1,1)=app.SchuhA.Value
                DATA3.(tempquestions{1, 4}).(app.SubjectIDEditField.Value)(1,2)=app.SchuhA_2.Value
                DATA3.(tempquestions{1, 4}).(app.SubjectIDEditField.Value)(1,3)=app.SchuhA_3.Value
                DATA3.(tempquestions{1, 4}).(app.SubjectIDEditField.Value)(1,4)=app.SchuhA_4.Value
                app.AllStabButton.FontColor = [0 1 0];
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename,'DATA3', '-append')
                app.SchuhA.Value = 50;
                app.SchuhA_2.Value = 50;
                app.SchuhA_3.Value = 50;
                app.SchuhA_4.Value = 50;
            elseif  value==1 && app.SizeButton.Value == true
                DATA4.(tempquestions{1, 5}).(app.SubjectIDEditField.Value)(1,1)=app.SchuhA.Value
                DATA4.(tempquestions{1, 5}).(app.SubjectIDEditField.Value)(1,2)=app.SchuhA_2.Value
                DATA4.(tempquestions{1, 5}).(app.SubjectIDEditField.Value)(1,3)=app.SchuhA_3.Value
                DATA4.(tempquestions{1, 5}).(app.SubjectIDEditField.Value)(1,4)=app.SchuhA_4.Value
                app.SizeButton.FontColor = [0 1 0];
                filename=strcat('C:\Users\maipa\Desktop\', 'QUESTIONARE_',(app.SubjectIDEditField.Value), '.mat')
                save(filename,'DATA4', '-append')
                
            end
            
        end

        % Value changed function: MilageEditField
        function MilageEditFieldValueChanged(app, event)
            value = app.MilageEditField.Value;
            
        end

        % Selection changed function: QuestionsButtonGroup
        function QuestionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.QuestionsButtonGroup.SelectedObject;
            if  app.ComiButton.Value == true;
                
                
                app.ComiButton.FontWeight = 'bold';
                app.ForFootStabButton.FontWeight = 'normal';
                app.BackFootStabButton.FontWeight = 'normal';
                app.AllStabButton.FontWeight = 'normal';
                app.SizeButton.FontWeight = 'normal';
            elseif  app.ForFootStabButton.Value == true;
                
                app.ComiButton.FontWeight = 'normal';
                app.ForFootStabButton.FontWeight = 'bold';
                app.BackFootStabButton.FontWeight = 'normal';
                app.AllStabButton.FontWeight = 'normal';
                app.SizeButton.FontWeight = 'normal';
            elseif  app.BackFootStabButton.Value == true;
                
                app.ComiButton.FontWeight = 'normal';
                app.ForFootStabButton.FontWeight = 'normal';
                app.BackFootStabButton.FontWeight = 'bold';
                app.AllStabButton.FontWeight = 'normal';
                app.SizeButton.FontWeight = 'normal';
            elseif  app.AllStabButton.Value == true;
                
                app.ComiButton.FontWeight = 'normal';
                app.ForFootStabButton.FontWeight = 'normal';
                app.BackFootStabButton.FontWeight = 'normal';
                app.AllStabButton.FontWeight = 'bold';
                app.SizeButton.FontWeight = 'normal';
            elseif  app.SizeButton.Value == true;
                
                app.ComiButton.FontWeight = 'normal';
                app.ForFootStabButton.FontWeight = 'normal';
                app.BackFootStabButton.FontWeight = 'normal';
                app.AllStabButton.FontWeight = 'normal';
                app.SizeButton.FontWeight = 'bold';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.con1Label = uilabel(app.UIFigure);
            app.con1Label.Text = 'con1';
            app.SchuhA = uislider(app.UIFigure);
            app.SchuhA.MajorTicks = [];
            app.SchuhA.ValueChangedFcn = createCallbackFcn(app, @SchuhAValueChanged, true);
            app.SchuhA.MinorTicks = [];
            app.SchuhA.Value = 50;
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '0';
            app.con2Label = uilabel(app.UIFigure);
            app.con2Label.Text = 'con2';
            app.SchuhA_2 = uislider(app.UIFigure);
            app.SchuhA_2.MajorTicks = [];
            app.SchuhA_2.ValueChangedFcn = createCallbackFcn(app, @SchuhA_2ValueChanged, true);
            app.SchuhA_2.MinorTicks = [];
            app.SchuhA_2.Value = 50;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '0';
            app.con3Label = uilabel(app.UIFigure);
            app.con3Label.Text = 'con3';
            app.SchuhA_3 = uislider(app.UIFigure);
            app.SchuhA_3.MajorTicks = [];
            app.SchuhA_3.ValueChangedFcn = createCallbackFcn(app, @SchuhA_3ValueChanged, true);
            app.SchuhA_3.MinorTicks = [];
            app.SchuhA_3.Value = 50;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '0';
            app.con4Label = uilabel(app.UIFigure);
            app.con4Label.Text = 'con4';
            app.SchuhA_4 = uislider(app.UIFigure);
            app.SchuhA_4.MajorTicks = [];
            app.SchuhA_4.MajorTickLabels = {};
            app.SchuhA_4.ValueChangedFcn = createCallbackFcn(app, @SchuhA_4ValueChanged, true);
            app.SchuhA_4.MinorTicks = [];
            app.SchuhA_4.Value = 50;
            app.saveButton = uibutton(app.UIFigure, 'state');
            app.saveButton.ValueChangedFcn = createCallbackFcn(app, @saveButtonValueChanged3, true);
            app.saveButton.Text = 'save';
            app.OrderShoeEditFieldLabel = uilabel(app.UIFigure);
            app.OrderShoeEditFieldLabel.Text = 'OrderShoe';
            app.OrderShoeEditField = uieditfield(app.UIFigure, 'text');
            app.OrderShoeEditField.Value = 'con1, con2, con3, con4';
            app.OkButton = uibutton(app.UIFigure, 'state');
            app.OkButton.ValueChangedFcn = createCallbackFcn(app, @OkButtonValueChanged, true);
            app.OkButton.Text = 'Ok';
            app.sepreateconditionswithcommaLabel = uilabel(app.UIFigure);
            app.sepreateconditionswithcommaLabel.Text = 'sepreate conditions with , (comma)';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Value = 'P001';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.MassEditField.Value = 75;
            app.kgLabel = uilabel(app.UIFigure);
            app.kgLabel.Text = 'kg';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 1.8;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.GenderButtonGroup = uibuttongroup(app.UIFigure);
            app.GenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenderButtonGroupSelectionChanged, true);
            app.GenderButtonGroup.Title = 'Gender';
            app.femaleButton = uiradiobutton(app.GenderButtonGroup);
            app.femaleButton.Text = 'female';
            app.femaleButton.Value = true;
            app.maleButton = uiradiobutton(app.GenderButtonGroup);
            app.maleButton.Text = 'male';
            app.MilageEditFieldLabel = uilabel(app.UIFigure);
            app.MilageEditFieldLabel.Text = 'Milage';
            app.MilageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MilageEditField.ValueChangedFcn = createCallbackFcn(app, @MilageEditFieldValueChanged, true);
            app.MilageEditField.Value = 1;
            app.QuestionsButtonGroup = uibuttongroup(app.UIFigure);
            app.QuestionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QuestionsButtonGroupSelectionChanged, true);
            app.QuestionsButtonGroup.Title = 'Questions';
            app.ComiButton = uiradiobutton(app.QuestionsButtonGroup);
            app.ComiButton.Text = 'Comi';
            app.ComiButton.Value = true;
            app.ForFootStabButton = uiradiobutton(app.QuestionsButtonGroup);
            app.ForFootStabButton.Text = 'ForFootStab';
            app.BackFootStabButton = uiradiobutton(app.QuestionsButtonGroup);
            app.BackFootStabButton.Text = 'BackFootStab';
            app.AllStabButton = uiradiobutton(app.QuestionsButtonGroup);
            app.AllStabButton.Text = 'AllStab';
            app.SizeButton = uiradiobutton(app.QuestionsButtonGroup);
            app.SizeButton.Text = 'Size';
            app.kmweekLabel = uilabel(app.UIFigure);
            app.kmweekLabel.Text = 'km/week';
            app.badLabel = uilabel(app.UIFigure);
            app.badLabel.Text = 'bad';
            app.goodLabel = uilabel(app.UIFigure);
            app.goodLabel.Text = 'good';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end